classdef gui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        uipanel1       matlab.ui.container.Panel
        uipanel2       matlab.ui.container.Panel
        text9          matlab.ui.control.Label
        text14         matlab.ui.control.Label
        txt_epocas     matlab.ui.control.EditField
        text12         matlab.ui.control.Label
        txt_neuronios  matlab.ui.control.EditField
        text16         matlab.ui.control.Label
        popuptreino    matlab.ui.control.DropDown
        text17         matlab.ui.control.Label
        popupsaida     matlab.ui.control.DropDown
        uipanel5       matlab.ui.container.Panel
        p_camada1      matlab.ui.control.DropDown
        text18         matlab.ui.control.Label
        text19         matlab.ui.control.Label
        p_camada2      matlab.ui.control.DropDown
        text20         matlab.ui.control.Label
        p_camada4      matlab.ui.control.DropDown
        text21         matlab.ui.control.Label
        p_camada5      matlab.ui.control.DropDown
        text22         matlab.ui.control.Label
        p_camada3      matlab.ui.control.DropDown
        text35         matlab.ui.control.Label
        popupTop       matlab.ui.control.DropDown
        popupcamadas   matlab.ui.control.DropDown
        uipanel6       matlab.ui.container.Panel
        uipanel7       matlab.ui.container.Panel
        txt_treino     matlab.ui.control.EditField
        text24         matlab.ui.control.Label
        text27         matlab.ui.control.Label
        txt_teste      matlab.ui.control.EditField
        text25         matlab.ui.control.Label
        txt_val        matlab.ui.control.EditField
        Switch_Seg     matlab.ui.control.RockerSwitch
        uipanel8       matlab.ui.container.Panel
        text29         matlab.ui.control.Label
        text30         matlab.ui.control.Label
        checkbox_p1    matlab.ui.control.CheckBox
        checkbox_p2    matlab.ui.control.CheckBox
        text33         matlab.ui.control.Label
        text34         matlab.ui.control.Label
        checkbox_p3    matlab.ui.control.CheckBox
        checkbox_p4    matlab.ui.control.CheckBox
        btn_guardar    matlab.ui.control.Button
        btn_carregar   matlab.ui.control.Button
        btn_treinar    matlab.ui.control.Button
        btn_testar     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function gui_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
 
            % Choose default command line output for gui
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            set(handles.txt_neuronios,'String',10);
            set(handles.txt_epocas,'String',100);
            set(handles.txt_teste ,'String',0.15);
            set(handles.txt_val,'String',0.15);
            set(handles.txt_treino,'String',0.7);
            set(handles.p_camada2 ,'Enable','off');
            set(handles.p_camada3 ,'Enable','off');
            set(handles.p_camada4 ,'Enable','off');
            set(handles.p_camada5 ,'Enable','off');
            set(handles.txt_teste ,'Enable','off');
            set(handles.txt_val,'Enable','off');
            set(handles.txt_treino,'Enable','off');
            
    
             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
             principal(0,handles.checkbox_p1,handles.checkbox_p2,handles.checkbox_p3,handles.checkbox_p4,handles.popupcamadas,handles.txt_neuronios,handles.txt_epocas,handles.p_camada1,handles.p_camada2,handles.p_camada3,handles.p_camada4,handles.p_camada5,handles.popuptreino,handles.popupTop,handles.popupsaida, app.Switch_Seg, app.txt_treino, app.txt_val, app.txt_teste);


        end

        % Button pushed function: btn_treinar
        function btn_treinar_Callback(app, event)

            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
               principal(1,handles.checkbox_p1,handles.checkbox_p2,handles.checkbox_p3,handles.checkbox_p4,handles.popupcamadas,handles.txt_neuronios,handles.txt_epocas,handles.p_camada1,handles.p_camada2,handles.p_camada3,handles.p_camada4,handles.p_camada5,handles.popuptreino,handles.popupTop,handles.popupsaida, app.Switch_Seg, app.txt_treino, app.txt_val, app.txt_teste);
               app.btn_guardar.Enable = 'On';
               app.btn_testar.Enable = 'On';


        end

        % Value changed function: checkbox_p4
        function checkbox_p4_Callback(app, event)
           
        end

        % Value changed function: popupcamadas
        function popupcamadas_Callback(app, event)
            checked1=event.Value;
            
            if checked1=='1'
                
                set(app.p_camada2 ,'Enable','off');
                set(app.p_camada3 ,'Enable','off');
                set(app.p_camada4 ,'Enable','off');
                set(app.p_camada5 ,'Enable','off');
            
            elseif checked1=='2'
                set(app.p_camada2 ,'Enable','on');
                set(app.p_camada3 ,'Enable','off');
                set(app.p_camada4 ,'Enable','off');
                set(app.p_camada5 ,'Enable','off');
            
            elseif checked1=='3'
                set(app.p_camada2 ,'Enable','on');
                set(app.p_camada3 ,'Enable','on');
                set(app.p_camada4 ,'Enable','off');
                set(app.p_camada5 ,'Enable','off');
            
            elseif checked1=='4'
                set(app.p_camada2 ,'Enable','on');
                set(app.p_camada3 ,'Enable','on');
                set(app.p_camada4 ,'Enable','on');
                set(app.p_camada5 ,'Enable','off');
            
            elseif checked1=='5'
                set(app.p_camada2 ,'Enable','on');
                set(app.p_camada3 ,'Enable','on');
                set(app.p_camada4 ,'Enable','on');
                set(app.p_camada5 ,'Enable','on');
            end
        end

        % Value changed function: Switch_Seg
        function SwitchValueChanged(app, event)
            
            if strcmpi(app.Switch_Seg.Value,'Off')==1
                app.txt_teste.Enable='off';
                app.txt_val.Enable='off';
                app.txt_treino.Enable='off';
                
            elseif strcmpi(app.Switch_Seg.Value,'On')==1
                app.txt_teste.Enable='on';
                app.txt_val.Enable='on';
                app.txt_treino.Enable='on';
            end
        end

        % Button pushed function: btn_testar
        function btn_testarButtonPushed(app, event)
      
             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
             principal(2,handles.checkbox_p1,handles.checkbox_p2,handles.checkbox_p3,handles.checkbox_p4,handles.popupcamadas,handles.txt_neuronios,handles.txt_epocas,handles.p_camada1,handles.p_camada2,handles.p_camada3,handles.p_camada4,handles.p_camada5,handles.popuptreino,handles.popupTop,handles.popupsaida, app.Switch_Seg, app.txt_treino, app.txt_val, app.txt_teste);


        end

        % Button pushed function: btn_guardar
        function btn_guardarButtonPushed(app, event)
               [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
     
               principal(3,handles.checkbox_p1,handles.checkbox_p2,handles.checkbox_p3,handles.checkbox_p4,handles.popupcamadas,handles.txt_neuronios,handles.txt_epocas,handles.p_camada1,handles.p_camada2,handles.p_camada3,handles.p_camada4,handles.p_camada5,handles.popuptreino,handles.popupTop,handles.popupsaida, app.Switch_Seg, app.txt_treino, app.txt_val, app.txt_teste);
          
        end

        % Button pushed function: btn_carregar
        function btn_carregarButtonPushed(app, event)
            
              [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
             principal(4,handles.checkbox_p1,handles.checkbox_p2,handles.checkbox_p3,handles.checkbox_p4,handles.popupcamadas,handles.txt_neuronios,handles.txt_epocas,handles.p_camada1,handles.p_camada2,handles.p_camada3,handles.p_camada4,handles.p_camada5,handles.popuptreino,handles.popupTop,handles.popupsaida, app.Switch_Seg, app.txt_treino, app.txt_val, app.txt_teste);
             app.btn_testar.Enable = 'On';
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Redes Neuronais';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Redes Neuronais';
            app.uipanel1.Tag = 'uipanel1';
            app.uipanel2 = uipanel(app.uipanel1);
            app.uipanel2.Tag = 'uipanel2';
            app.text9 = uilabel(app.uipanel2);
            app.text9.Tag = 'text9';
            app.text9.Text = {''; 'Nº Camadas: '};
            app.text14 = uilabel(app.uipanel2);
            app.text14.Tag = 'text14';
            app.text14.Text = {''; 'Nº Épocas: '};
            app.txt_epocas = uieditfield(app.uipanel2, 'text');
            app.txt_epocas.Tag = 'txt_epocas';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Tag = 'text12';
            app.text12.Text = {''; 'Nº Neurónios:'};
            app.txt_neuronios = uieditfield(app.uipanel2, 'text');
            app.txt_neuronios.Tag = 'txt_neuronios';
            app.text16 = uilabel(app.uipanel2);
            app.text16.Tag = 'text16';
            app.text16.Text = {''; 'F_Treino:'};
            app.popuptreino = uidropdown(app.uipanel2);
            app.popuptreino.Items = {'traingdx', 'trainlm', 'trainrp'};
            app.popuptreino.Tag = 'popuptreino';
            app.popuptreino.Value = 'traingdx';
            app.text17 = uilabel(app.uipanel2);
            app.text17.Tag = 'text17';
            app.text17.Text = {''; 'F_Saída:'};
            app.popupsaida = uidropdown(app.uipanel2);
            app.popupsaida.Items = {'tansig', 'logsig', 'purelin'};
            app.popupsaida.Tag = 'popupsaida';
            app.popupsaida.Value = 'tansig';
            app.uipanel5 = uipanel(app.uipanel2);
            app.uipanel5.Title = 'Funçao de Ativação';
            app.uipanel5.Tag = 'uipanel5';
            app.p_camada1 = uidropdown(app.uipanel5);
            app.p_camada1.Items = {'tansig', 'logsig', 'purelin'};
            app.p_camada1.Tag = 'p_camada1';
            app.p_camada1.Value = 'purelin';
            app.text18 = uilabel(app.uipanel5);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Camada 1:';
            app.text19 = uilabel(app.uipanel5);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Camada 2:';
            app.p_camada2 = uidropdown(app.uipanel5);
            app.p_camada2.Items = {'tansig', 'logsig', 'purelin'};
            app.p_camada2.Tag = 'p_camada2';
            app.p_camada2.Value = 'tansig';
            app.text20 = uilabel(app.uipanel5);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Camada 4:';
            app.p_camada4 = uidropdown(app.uipanel5);
            app.p_camada4.Items = {'tansig', 'logsig', 'purelin'};
            app.p_camada4.Tag = 'p_camada4';
            app.p_camada4.Value = 'tansig';
            app.text21 = uilabel(app.uipanel5);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Camada 5:';
            app.p_camada5 = uidropdown(app.uipanel5);
            app.p_camada5.Items = {'tansig', 'logsig', 'purelin'};
            app.p_camada5.Tag = 'p_camada5';
            app.p_camada5.Value = 'tansig';
            app.text22 = uilabel(app.uipanel5);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Camada 3:';
            app.p_camada3 = uidropdown(app.uipanel5);
            app.p_camada3.Items = {'tansig', 'logsig', 'purelin'};
            app.p_camada3.Tag = 'p_camada3';
            app.p_camada3.Value = 'tansig';
            app.text35 = uilabel(app.uipanel2);
            app.text35.Tag = 'text35';
            app.text35.Text = {''; 'Topologia:'};
            app.popupTop = uidropdown(app.uipanel2);
            app.popupTop.Items = {'cascadeforwardnet', 'feedforwardnet', 'patternet'};
            app.popupTop.Tag = 'popupTop';
            app.popupTop.Value = 'cascadeforwardnet';
            app.popupcamadas = uidropdown(app.uipanel2);
            app.popupcamadas.Items = {'1', '2', '3', '4', '5'};
            app.popupcamadas.ValueChangedFcn = createCallbackFcn(app, @popupcamadas_Callback, true);
            app.popupcamadas.Tag = 'popupcamadas';
            app.popupcamadas.Value = '1';
            app.uipanel6 = uipanel(app.uipanel1);
            app.uipanel6.Tag = 'uipanel6';
            app.uipanel7 = uipanel(app.uipanel6);
            app.uipanel7.Title = 'Segmentação';
            app.uipanel7.Tag = 'uipanel7';
            app.txt_treino = uieditfield(app.uipanel7, 'text');
            app.txt_treino.Tag = 'txt_treino';
            app.text24 = uilabel(app.uipanel7);
            app.text24.Tag = 'text24';
            app.text24.Text = {''; '% Treino'};
            app.text27 = uilabel(app.uipanel7);
            app.text27.Tag = 'text27';
            app.text27.Text = {''; '% Teste'};
            app.txt_teste = uieditfield(app.uipanel7, 'text');
            app.txt_teste.Tag = 'txt_teste';
            app.text25 = uilabel(app.uipanel7);
            app.text25.Tag = 'text25';
            app.text25.Text = {''; '% Validação'};
            app.txt_val = uieditfield(app.uipanel7, 'text');
            app.txt_val.Tag = 'txt_val';
            app.Switch_Seg = uiswitch(app.uipanel7, 'rocker');
            app.Switch_Seg.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.uipanel8 = uipanel(app.uipanel6);
            app.uipanel8.Title = 'Imagens';
            app.uipanel8.Tag = 'uipanel8';
            app.text29 = uilabel(app.uipanel8);
            app.text29.Tag = 'text29';
            app.text29.Text = 'Pasta 1:';
            app.text30 = uilabel(app.uipanel8);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Pasta 2:';
            app.checkbox_p1 = uicheckbox(app.uipanel8);
            app.checkbox_p1.Tag = 'checkbox_p1';
            app.checkbox_p1.Text = '';
            app.checkbox_p1.Value = true;
            app.checkbox_p2 = uicheckbox(app.uipanel8);
            app.checkbox_p2.Tag = 'checkbox_p2';
            app.checkbox_p2.Text = '';
            app.text33 = uilabel(app.uipanel8);
            app.text33.Tag = 'text33';
            app.text33.Text = 'Pasta 3:';
            app.text34 = uilabel(app.uipanel8);
            app.text34.Tag = 'text34';
            app.text34.Text = 'Pasta 4:';
            app.checkbox_p3 = uicheckbox(app.uipanel8);
            app.checkbox_p3.Tag = 'checkbox_p3';
            app.checkbox_p3.Text = '';
            app.checkbox_p4 = uicheckbox(app.uipanel8);
            app.checkbox_p4.ValueChangedFcn = createCallbackFcn(app, @checkbox_p4_Callback, true);
            app.checkbox_p4.Tag = 'checkbox_p4';
            app.checkbox_p4.Text = '';
            app.btn_guardar = uibutton(app.uipanel1, 'push');
            app.btn_guardar.ButtonPushedFcn = createCallbackFcn(app, @btn_guardarButtonPushed, true);
            app.btn_guardar.Tag = 'btn_guardar';
            app.btn_guardar.Enable = 'off';
            app.btn_guardar.Text = 'Guardar RN';
            app.btn_carregar = uibutton(app.uipanel1, 'push');
            app.btn_carregar.ButtonPushedFcn = createCallbackFcn(app, @btn_carregarButtonPushed, true);
            app.btn_carregar.Tag = 'btn_carregar';
            app.btn_carregar.Text = 'Carregar RN';
            app.btn_treinar = uibutton(app.uipanel1, 'push');
            app.btn_treinar.ButtonPushedFcn = createCallbackFcn(app, @btn_treinar_Callback, true);
            app.btn_treinar.Tag = 'btn_treinar';
            app.btn_treinar.Text = 'Treinar RN';
            app.btn_testar = uibutton(app.uipanel1, 'push');
            app.btn_testar.ButtonPushedFcn = createCallbackFcn(app, @btn_testarButtonPushed, true);
            app.btn_testar.Tag = 'btn_testar';
            app.btn_testar.Enable = 'off';
            app.btn_testar.Text = 'Testar RN';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)gui_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end